classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ModeMenu                   matlab.ui.container.Menu
        CalibrationMenu            matlab.ui.container.Menu
        sensorsMenu                matlab.ui.container.Menu
        pumpsMenu                  matlab.ui.container.Menu
        valvesMenu                 matlab.ui.container.Menu
        ControllerMenu             matlab.ui.container.Menu
        Menu_4                     matlab.ui.container.Menu
        Menu2_3                    matlab.ui.container.Menu
        Menu3_2                    matlab.ui.container.Menu
        Menu4                      matlab.ui.container.Menu
        Menu5                      matlab.ui.container.Menu
        Menu6                      matlab.ui.container.Menu
        Menu7                      matlab.ui.container.Menu
        ValvePanel                 matlab.ui.container.Panel
        confirmButton_54           matlab.ui.control.Button
        confirmButton_53           matlab.ui.control.Button
        confirmButton_52           matlab.ui.control.Button
        confirmButton_51           matlab.ui.control.Button
        confirmButton_50           matlab.ui.control.Button
        confirmButton_49           matlab.ui.control.Button
        confirmButton_48           matlab.ui.control.Button
        confirmButton_47           matlab.ui.control.Button
        confirmButton_46           matlab.ui.control.Button
        confirmButton_45           matlab.ui.control.Button
        confirmButton_44           matlab.ui.control.Button
        confirmButton_43           matlab.ui.control.Button
        confirmButton_42           matlab.ui.control.Button
        confirmButton_41           matlab.ui.control.Button
        confirmButton_40           matlab.ui.control.Button
        confirmButton_39           matlab.ui.control.Button
        confirmButton_38           matlab.ui.control.Button
        confirmButton_37           matlab.ui.control.Button
        confirmButton_36           matlab.ui.control.Button
        confirmButton_35           matlab.ui.control.Button
        confirmButton_34           matlab.ui.control.Button
        confirmButton_33           matlab.ui.control.Button
        confirmButton_32           matlab.ui.control.Button
        confirmButton_31           matlab.ui.control.Button
        confirmButton_30           matlab.ui.control.Button
        confirmButton_2            matlab.ui.control.Button
        confirmButton_1            matlab.ui.control.Button
        Tank3Label_2               matlab.ui.control.Label
        Tank2Label_2               matlab.ui.control.Label
        Tank1Label_2               matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        StopButton_2               matlab.ui.control.Button
        SaveVisualizationButton_2  matlab.ui.control.Button
        StartButton_2              matlab.ui.control.Button
        PumpPanel                  matlab.ui.container.Panel
        SensorPanel                matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function recordPoint(app, tank, level, button)

            mdl = "Copy_2_of_calibration_sensors";
        
            blockName = sprintf("confirmlevel%d_%d", level, tank);
            blockPath = mdl + "/" + blockName;
        
            simStatus = get_param(mdl, "SimulationStatus");
        
            if ~strcmp(simStatus, "running")
                uialert(app.UIFigure, ...
                    "Please start Sensor Calibration first.", ...
                    "Simulation not running");
                return
            end
        
            set_param(blockPath, "Value", "1");
            pause(0.2);
            set_param(blockPath, "Value", "0");
        
            button.Text = "Recorded";
            button.Enable = "off";
        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: sensorsMenu
        function sensorsMenuSelected(app, event)
            app.SensorPanel.Visible = "on";
            app.PumpPanel.Visible   = "off";
            app.ValvePanel.Visible  = "off";
        end

        % Menu selected function: pumpsMenu
        function pumpsMenuSelected(app, event)
            app.SensorPanel.Visible = "off";
            app.PumpPanel.Visible   = "on";
            app.ValvePanel.Visible  = "off";
        end

        % Menu selected function: valvesMenu
        function valvesMenuSelected(app, event)
            app.SensorPanel.Visible = "off";
            app.PumpPanel.Visible   = "off";
            app.ValvePanel.Visible  = "on";
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";

            try
                load_system(mdl);

                set_param(mdl, "StopTime", "inf");

                set_param(mdl, ...
                    "SimulationCommand", ...
                    "start");

                pause(0.3);

                status = get_param(mdl, "SimulationStatus");

                if strcmp(status, "running")

                    app.StartButton.Text = "Running";
                    app.StartButton.Enable = "off";

                else

                    app.StartButton.Text = "Start";

                    uialert(app.UIFigure, ...
                        "Model status: " + string(status), ...
                        "Simulation Error");
                end

            catch ME

                app.StartButton.Text = "Start";

                uialert(app.UIFigure, ...
                    ME.message, ...
                    "Start Error");

            end
        end

        % Button pushed function: confirmButton_1
        function confirmButton_1Pushed(app, event)
            app.recordPoint(1, 1, app.confirmButton_1);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            mdl = "Copy_2_of_calibration_sensors";

            set_param(mdl, ...
                "SimulationCommand", ...
                "stop");
        end

        % Button pushed function: confirmButton_2
        function confirmButton_2Pushed(app, event)
            app.recordPoint(2, 1, app.confirmButton_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModeMenu = uimenu(app.UIFigure);
            app.ModeMenu.Text = 'Mode';
            app.CalibrationMenu = uimenu(app.ModeMenu);
            app.CalibrationMenu.Text = 'Calibration';
            app.sensorsMenu = uimenu(app.CalibrationMenu);
            app.sensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @sensorsMenuSelected, true);
            app.sensorsMenu.Text = 'sensors';
            app.pumpsMenu = uimenu(app.CalibrationMenu);
            app.pumpsMenu.MenuSelectedFcn = createCallbackFcn(app, @pumpsMenuSelected, true);
            app.pumpsMenu.Text = 'pumps';
            app.valvesMenu = uimenu(app.CalibrationMenu);
            app.valvesMenu.MenuSelectedFcn = createCallbackFcn(app, @valvesMenuSelected, true);
            app.valvesMenu.Text = 'valves';
            app.ControllerMenu = uimenu(app.ModeMenu);
            app.ControllerMenu.Text = 'Controller';
            app.Menu_4 = uimenu(app.ControllerMenu);
            app.Menu_4.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ControllerMenu);
            app.Menu2_3.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.ControllerMenu);
            app.Menu3_2.Text = 'Menu3';
            app.Menu4 = uimenu(app.ControllerMenu);
            app.Menu4.Text = 'Menu4';
            app.Menu5 = uimenu(app.ControllerMenu);
            app.Menu5.Text = 'Menu5';
            app.Menu6 = uimenu(app.ControllerMenu);
            app.Menu6.Text = 'Menu6';
            app.Menu7 = uimenu(app.ControllerMenu);
            app.Menu7.Text = 'Menu7';
            app.SensorPanel = uipanel(app.UIFigure);
            app.PumpPanel = uipanel(app.UIFigure);
            app.ValvePanel = uipanel(app.UIFigure);
            app.StartButton_2 = uibutton(app.ValvePanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.SaveVisualizationButton_2 = uibutton(app.ValvePanel, 'push');
            app.SaveVisualizationButton_2.Text = 'Save & Visualization';
            app.StopButton_2 = uibutton(app.ValvePanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.Label_10 = uilabel(app.ValvePanel);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.ValvePanel);
            app.Label_11.Text = '15';
            app.Label_12 = uilabel(app.ValvePanel);
            app.Label_12.Text = '20';
            app.Label_13 = uilabel(app.ValvePanel);
            app.Label_13.Text = '25';
            app.Label_14 = uilabel(app.ValvePanel);
            app.Label_14.Text = '30';
            app.Label_15 = uilabel(app.ValvePanel);
            app.Label_15.Text = '35';
            app.Label_16 = uilabel(app.ValvePanel);
            app.Label_16.Text = '40';
            app.Label_17 = uilabel(app.ValvePanel);
            app.Label_17.Text = '45';
            app.Label_18 = uilabel(app.ValvePanel);
            app.Label_18.Text = '50';
            app.Tank1Label_2 = uilabel(app.ValvePanel);
            app.Tank1Label_2.Text = 'Tank1';
            app.Tank2Label_2 = uilabel(app.ValvePanel);
            app.Tank2Label_2.Text = 'Tank2';
            app.Tank3Label_2 = uilabel(app.ValvePanel);
            app.Tank3Label_2.Text = 'Tank3';
            app.confirmButton_1 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_1.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_1Pushed, true);
            app.confirmButton_1.Text = 'confirm';
            app.confirmButton_2 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @confirmButton_2Pushed, true);
            app.confirmButton_2.Text = 'confirm';
            app.confirmButton_30 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_30.Text = 'confirm';
            app.confirmButton_31 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_31.Text = 'confirm';
            app.confirmButton_32 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_32.Text = 'confirm';
            app.confirmButton_33 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_33.Text = 'confirm';
            app.confirmButton_34 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_34.Text = 'confirm';
            app.confirmButton_35 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_35.Text = 'confirm';
            app.confirmButton_36 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_36.Text = 'confirm';
            app.confirmButton_37 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_37.Text = 'confirm';
            app.confirmButton_38 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_38.Text = 'confirm';
            app.confirmButton_39 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_39.Text = 'confirm';
            app.confirmButton_40 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_40.Text = 'confirm';
            app.confirmButton_41 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_41.Text = 'confirm';
            app.confirmButton_42 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_42.Text = 'confirm';
            app.confirmButton_43 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_43.Text = 'confirm';
            app.confirmButton_44 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_44.Text = 'confirm';
            app.confirmButton_45 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_45.Text = 'confirm';
            app.confirmButton_46 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_46.Text = 'confirm';
            app.confirmButton_47 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_47.Text = 'confirm';
            app.confirmButton_48 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_48.Text = 'confirm';
            app.confirmButton_49 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_49.Text = 'confirm';
            app.confirmButton_50 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_50.Text = 'confirm';
            app.confirmButton_51 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_51.Text = 'confirm';
            app.confirmButton_52 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_52.Text = 'confirm';
            app.confirmButton_53 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_53.Text = 'confirm';
            app.confirmButton_54 = uibutton(app.ValvePanel, 'push');
            app.confirmButton_54.Text = 'confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end